classdef ecg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalyseECG                     matlab.ui.Figure
        AnalyseurECG                   matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SpectresTab                    matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        RepresentationfrequentielleButton  matlab.ui.control.Button
        SpectredePhasesButton          matlab.ui.control.Button
        SpectredAmplitudeButton        matlab.ui.control.Button
        PropritsStatistiquesTab        matlab.ui.container.Tab
        PropritsStatitisquesLabel      matlab.ui.control.Label
        AfficherlespropritsButton      matlab.ui.control.Button
        MoyenneTextAreaLabel           matlab.ui.control.Label
        MoyenneTextArea                matlab.ui.control.TextArea
        VarianceTextAreaLabel          matlab.ui.control.Label
        VarianceTextArea               matlab.ui.control.TextArea
        EcartTypeTextAreaLabel         matlab.ui.control.Label
        EcartTypeTextArea              matlab.ui.control.TextArea
        MaximumTextAreaLabel           matlab.ui.control.Label
        MaximumTextArea                matlab.ui.control.TextArea
        MinimumTextAreaLabel           matlab.ui.control.Label
        MinimumTextArea                matlab.ui.control.TextArea
        PropritsEnrgtiqueTab           matlab.ui.container.Tab
        PropritsEnrgtiquesLabel        matlab.ui.control.Label
        AfficherlespropritsButton_2    matlab.ui.control.Button
        EnergieTextAreaLabel           matlab.ui.control.Label
        EnergieTextArea                matlab.ui.control.TextArea
        PuissanceMoyenneTextAreaLabel  matlab.ui.control.Label
        PuissanceMoyenneTextArea       matlab.ui.control.TextArea
        ComparaisonTab                 matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        ChargerSIgnalAButton           matlab.ui.control.Button
        ChargerSignalBButton           matlab.ui.control.Button
        ComparerButton                 matlab.ui.control.Button
        ChargerSignalButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        AfficherSignalButton           matlab.ui.control.Button
        DetectiondespicsButton         matlab.ui.control.Button
    end


    properties (Access = public)
        gain = 200;
        base = 1024;
        val = ''; % Description
        cval1 = '';
        cval2 = '';
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: AfficherSignalButton
        function AfficherSignalButtonPushed(app, event)
             ECGsignal =(app.val - app.base)/app.gain;
            Fs = 360;
            t = (0:length(ECGsignal) - 1)/Fs;
            
           plot(app.UIAxes,t,ECGsignal,'green');
        end

        % Button pushed function: AfficherlespropritsButton
        function AfficherlespropritsButtonPushed(app, event)
            ECGsignal =(app.val - app.base)/app.gain;
            message = sprintf('%.5f ', mean(ECGsignal));
            app.MoyenneTextArea.Value = {message};
            
            ECGsignal =(app.val - app.base)/app.gain;
            message = sprintf('%.5f ', var(ECGsignal));
            app.VarianceTextArea.Value = {message};
            
            ECGsignal =(app.val - app.base)/app.gain;
            message = sprintf('%.5f ', sqrt(var(ECGsignal)));
            app.EcartTypeTextArea.Value = {message};
            
            ECGsignal =(app.val - app.base)/app.gain;
            message = sprintf('%.5f ', max(ECGsignal));
            app.MaximumTextArea.Value = {message};
            
            ECGsignal =(app.val - app.base)/app.gain;
            message = sprintf('%.5f ', min(ECGsignal));
            app.MinimumTextArea.Value = {message};
        end

        % Button pushed function: AfficherlespropritsButton_2
        function AfficherlespropritsButton_2Pushed(app, event)
            ECGsignal =(app.val - app.base)/app.gain;
            power = rms(ECGsignal)^2
            message = sprintf('%.5f Watts(s)', power)
            app.PuissanceMoyenneTextArea.Value = {message};
            
            ECGsignal =(app.val - app.base)/app.gain;
            energy = (norm(ECGsignal)^2);
            message = sprintf('%.5f Joule(s)', energy)
            app.EnergieTextArea.Value = {message};
            
        end

        % Button pushed function: ChargerSIgnalAButton
        function ChargerSIgnalAButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Pick a MATLAB file');
            if isequal(filename,0) || isequal(pathname,0)
                disp('User pressed cancel')
            else
                disp(['User selected ', fullfile(pathname, filename)])
                fullpath = fullfile(pathname,filename);
                load(fullpath);
                app.cval1 = val;
                
                
            end
        end

        % Button pushed function: ChargerSignalBButton
        function ChargerSignalBButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Pick a MATLAB file');
            if isequal(filename,0) || isequal(pathname,0)
                disp('User pressed cancel')
            else
                disp(['User selected ', fullfile(pathname, filename)])
                fullpath = fullfile(pathname,filename);
                load(fullpath);
                app.cval2 = val;
                
                
            end
        end

        % Button pushed function: ChargerSignalButton
        function ChargerSignalButtonPushed(app, event)
            H = findobj('property', 'Value');
           disp(H);
     
        [filename, pathname] = uigetfile('*.mat', 'Pick a MATLAB code file');
        if isequal(filename,0) || isequal(pathname,0)
           disp('User pressed cancel')
        else
           disp(['User selected ', fullfile(pathname, filename)])
           fullpath = fullfile(pathname,filename);
            load(fullpath);
            app.val = val;
            app.TabGroup.Visible = 'on';
                
        
    end
        end

        % Button pushed function: ComparerButton
        function ComparerButtonPushed(app, event)
            signal1 =(app.cval1 - app.base)/app.gain;
            Fs = 360;
            t = (0:length(signal1) - 1)/Fs;
            signal2 =(app.cval2 - app.base)/app.gain;
            [acor,lag] = xcorr(signal1,signal2);
            [~,I] = max(abs(acor));
            timeDiff = lag(I)
            
           plot(app.UIAxes3,lag,acor);
        end

        % Button pushed function: DetectiondespicsButton
        function DetectiondespicsButtonPushed(app, event)
            
            ECG_raw = (app.val - app.base)/app.gain;
            
            
            % Scale the signal per the specifications of the ECG sensor
            ECG_adj = ECG_raw;
            
            % Filter the scaled signal using a Savitzky-Golay filter
            ECG_data = sgolayfilt(ECG_adj, 7, 41);
            
            t = 1:length(ECG_data);
            [~,locs_Rwave] = findpeaks(ECG_data,'MinPeakHeight',0.8,...
                                    'MinPeakDistance',100);
            
            % Remove Edge Wave Data
            locs_Rwave(locs_Rwave < 150 | locs_Rwave > (length(ECG_data) - 150)) = [];
            locs_Qwave = zeros(length(locs_Rwave),1);
            locs_Swave = zeros(length(locs_Rwave),1);
            locs_Qpre  = zeros(length(locs_Rwave),1);
            locs_Spost = zeros(length(locs_Rwave),1);
            QRS = zeros(length(locs_Rwave),1);
            
            % Find Q and S waves in the signal
            for ii = 1:length(locs_Rwave)
            window = ECG_data((locs_Rwave(ii)-80):(locs_Rwave(ii)+80));
            [d_peaks, locs_peaks] = findpeaks(-window, 'MinPeakDistance',40);
            [d,i] = sort(d_peaks, 'descend');
            locs_Qwave(ii) = locs_peaks(i(1))+(locs_Rwave(ii)-80);
            locs_Swave(ii) = locs_peaks(i(2))+(locs_Rwave(ii)-80);
            [d_QRS, locs_QRS] = findpeaks(window, 'MinPeakDistance', 10);
            [max_d, max_i] = max(d_QRS);
            locs_Q_flat = locs_QRS(max_i-1);
            locs_S_flat = locs_QRS(max_i+1);
            locs_Qpre(ii)  = locs_Q_flat+(locs_Rwave(ii)-80);
            locs_Spost(ii) = locs_S_flat+(locs_Rwave(ii)-80);
            QRS(ii) = locs_S_flat - locs_Q_flat;
            end
            
            % Calculate the heart rate
            myqrs = median(QRS);
            myheartrate = 60 ./ (median(locs_Rwave) ./ 1000)*2.8;
            
            locs_all = [locs_Qwave; locs_Rwave; locs_Swave; locs_Qpre; locs_Spost];
            ECG_all  = ECG_data(locs_all);
            
            [d,i] = sort(locs_all);
            ECG_sort = ECG_all(i);
            disp(myheartrate);
            findpeaks(ECG_raw);


            figure
            hold on
            plot(t,ECG_data);
            plot(locs_Qwave,ECG_data(locs_Qwave),'rs','MarkerFaceColor','g');
            plot(locs_Rwave,ECG_data(locs_Rwave),'rv','MarkerFaceColor','r');
            plot(locs_Swave,ECG_data(locs_Swave),'rs','MarkerFaceColor','b');
            plot(locs_Qpre, ECG_data(locs_Qpre), 'r>','MarkerFaceColor','c');
            plot(locs_Spost,ECG_data(locs_Spost),'r<','MarkerFaceColor','m');
            grid on
            % Adjust the plot to show 8 seconds worth of measurements
            ylim([-1 1]);
            xlim([0 4000]);
            title(sprintf('QRS = %f ms,  Heart Rate = %f / min', myqrs, myheartrate));
            xlabel('Samples'); ylabel('Voltage(mV)')
            legend('ECG signal','Q-wave','R-wave','S-wave','Q-pre','S-post');
        end

        % Button pushed function: RepresentationfrequentielleButton
        function RepresentationfrequentielleButtonPushed(app, event)
            ECGsignal =(app.val - app.base)/app.gain;
            
            ecgSignal=fft(ECGsignal);
            t = (0:length(ecgSignal)-1);
            
           plot(app.UIAxes2,t,ecgSignal,'green');
        end

        % Button pushed function: SpectredAmplitudeButton
        function SpectredAmplitudeButtonPushed(app, event)
            zoom(app.UIAxes2,'on');
            ECGsignal = (app.val - app.base)/app.gain;
            Fs = 360;
            t = (0:length(ECGsignal) - 1)/Fs;
            plot(app.UIAxes2,t,abs(fft(ECGsignal)),'yellow');
            
            
        end

        % Button pushed function: SpectredePhasesButton
        function SpectredePhasesButtonPushed(app, event)
            ECGsignal =(app.val - app.base)/app.gain;
            Fs = 360;
            t = (0:length(ECGsignal) - 1)/Fs;
            plot(app.UIAxes2,t,angle(fft(ECGsignal)),'red');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalyseECG = uifigure;
            app.AnalyseECG.Name = 'UI Figure';
            app.AnalyseurECG = uilabel(app.AnalyseECG);
            app.AnalyseurECG.Text = '                               ANALYSE DES ELECTROCARDIOGRAMMES';
            app.TabGroup = uitabgroup(app.AnalyseECG);
            app.SpectresTab = uitab(app.TabGroup);
            app.SpectresTab.Title = 'Spectres';
            app.UIAxes2 = uiaxes(app.SpectresTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.RepresentationfrequentielleButton = uibutton(app.SpectresTab, 'push');
            app.RepresentationfrequentielleButton.ButtonPushedFcn = createCallbackFcn(app, @RepresentationfrequentielleButtonPushed, true);
            app.RepresentationfrequentielleButton.Text = 'Representation frequentielle';
            app.SpectredePhasesButton = uibutton(app.SpectresTab, 'push');
            app.SpectredePhasesButton.ButtonPushedFcn = createCallbackFcn(app, @SpectredePhasesButtonPushed, true);
            app.SpectredePhasesButton.Text = 'Spectre de Phases';
            app.SpectredAmplitudeButton = uibutton(app.SpectresTab, 'push');
            app.SpectredAmplitudeButton.ButtonPushedFcn = createCallbackFcn(app, @SpectredAmplitudeButtonPushed, true);
            app.SpectredAmplitudeButton.Text = 'Spectre d''Amplitude';
            app.PropritsStatistiquesTab = uitab(app.TabGroup);
            app.PropritsStatistiquesTab.Title = 'Propriétés Statistiques';
            app.PropritsStatitisquesLabel = uilabel(app.PropritsStatistiquesTab);
            app.PropritsStatitisquesLabel.Text = '                                           Propriétés Statitisques';
            app.AfficherlespropritsButton = uibutton(app.PropritsStatistiquesTab, 'push');
            app.AfficherlespropritsButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherlespropritsButtonPushed, true);
            app.AfficherlespropritsButton.Text = 'Afficher les propriétés';
            app.MoyenneTextAreaLabel = uilabel(app.PropritsStatistiquesTab);
            app.MoyenneTextAreaLabel.Text = 'Moyenne';
            app.MoyenneTextArea = uitextarea(app.PropritsStatistiquesTab);
            app.VarianceTextAreaLabel = uilabel(app.PropritsStatistiquesTab);
            app.VarianceTextAreaLabel.Text = 'Variance';
            app.VarianceTextArea = uitextarea(app.PropritsStatistiquesTab);
            app.EcartTypeTextAreaLabel = uilabel(app.PropritsStatistiquesTab);
            app.EcartTypeTextAreaLabel.Text = 'Ecart-Type';
            app.EcartTypeTextArea = uitextarea(app.PropritsStatistiquesTab);
            app.MaximumTextAreaLabel = uilabel(app.PropritsStatistiquesTab);
            app.MaximumTextAreaLabel.Text = 'Maximum';
            app.MaximumTextArea = uitextarea(app.PropritsStatistiquesTab);
            app.MinimumTextAreaLabel = uilabel(app.PropritsStatistiquesTab);
            app.MinimumTextAreaLabel.Text = 'Minimum';
            app.MinimumTextArea = uitextarea(app.PropritsStatistiquesTab);
            app.PropritsEnrgtiqueTab = uitab(app.TabGroup);
            app.PropritsEnrgtiqueTab.Title = 'Propriétés Enérgétique';
            app.PropritsEnrgtiquesLabel = uilabel(app.PropritsEnrgtiqueTab);
            app.PropritsEnrgtiquesLabel.Text = 'Propriétés Enérgétiques';
            app.AfficherlespropritsButton_2 = uibutton(app.PropritsEnrgtiqueTab, 'push');
            app.AfficherlespropritsButton_2.ButtonPushedFcn = createCallbackFcn(app, @AfficherlespropritsButton_2Pushed, true);
            app.AfficherlespropritsButton_2.Text = 'Afficher les propriétés';
            app.EnergieTextAreaLabel = uilabel(app.PropritsEnrgtiqueTab);
            app.EnergieTextAreaLabel.Text = 'Energie';
            app.EnergieTextArea = uitextarea(app.PropritsEnrgtiqueTab);
            app.PuissanceMoyenneTextAreaLabel = uilabel(app.PropritsEnrgtiqueTab);
            app.PuissanceMoyenneTextAreaLabel.Text = 'Puissance Moyenne';
            app.PuissanceMoyenneTextArea = uitextarea(app.PropritsEnrgtiqueTab);
            app.ComparaisonTab = uitab(app.TabGroup);
            app.ComparaisonTab.Title = 'Comparaison';
            app.UIAxes3 = uiaxes(app.ComparaisonTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ChargerSIgnalAButton = uibutton(app.ComparaisonTab, 'push');
            app.ChargerSIgnalAButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerSIgnalAButtonPushed, true);
            app.ChargerSIgnalAButton.Text = 'Charger SIgnal A';
            app.ChargerSignalBButton = uibutton(app.ComparaisonTab, 'push');
            app.ChargerSignalBButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerSignalBButtonPushed, true);
            app.ChargerSignalBButton.Text = 'Charger Signal B';
            app.ComparerButton = uibutton(app.ComparaisonTab, 'push');
            app.ComparerButton.ButtonPushedFcn = createCallbackFcn(app, @ComparerButtonPushed, true);
            app.ComparerButton.Text = 'Comparer';
            app.ChargerSignalButton = uibutton(app.AnalyseECG, 'push');
            app.ChargerSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerSignalButtonPushed, true);
            app.ChargerSignalButton.Text = 'Charger Signal';
            app.UIAxes = uiaxes(app.AnalyseECG);
            title(app.UIAxes, 'Représentation Temporelle')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AfficherSignalButton = uibutton(app.AnalyseECG, 'push');
            app.AfficherSignalButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherSignalButtonPushed, true);
            app.AfficherSignalButton.Text = 'Afficher Signal';
            app.DetectiondespicsButton = uibutton(app.AnalyseECG, 'push');
            app.DetectiondespicsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectiondespicsButtonPushed, true);
            app.DetectiondespicsButton.Text = 'Detection des pics';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ecg

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalyseECG)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalyseECG)
        end
    end
end